classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        DeformedBottleLampLabel      matlab.ui.control.Label
        DeformedBottleLamp           matlab.ui.control.Lamp
        OverfilledLampLabel          matlab.ui.control.Label
        OverfilledLamp               matlab.ui.control.Lamp
        UnderfilledLampLabel         matlab.ui.control.Label
        UnderfilledLamp              matlab.ui.control.Lamp
        LabelNotPresentLampLabel     matlab.ui.control.Label
        LabelNotPresentLamp          matlab.ui.control.Lamp
        LabelNoStraightLampLabel     matlab.ui.control.Label
        LabelNoStraightLamp          matlab.ui.control.Lamp
        NoLabelPrintLampLabel        matlab.ui.control.Label
        NoLabelPrintLamp             matlab.ui.control.Lamp
        CapmissingLampLabel          matlab.ui.control.Label
        CapmissingLamp               matlab.ui.control.Lamp
        LoadanImageButton            matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
        NormalLamp_2Label            matlab.ui.control.Label
        NormalLamp_2                 matlab.ui.control.Lamp
        CreatedByLabel               matlab.ui.control.Label
        AbdullahThabitLabel          matlab.ui.control.Label
        ZohaibSalahuddinLabel        matlab.ui.control.Label
        TewodrosAregaLabel           matlab.ui.control.Label
        ImageProcessingProjectLabel  matlab.ui.control.Label
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            im_original=zeros(288,352);
            imshow(im_original,'Parent',app.UIAxes);
            set(app.UIAxes,'HandleVisibility','OFF');
            app.DeformedBottleLamp.Color='k';
            app.OverfilledLamp.Color='k';
            app.UnderfilledLamp.Color='k';
            app.LabelNotPresentLamp.Color='k';
            app.LabelNoStraightLamp.Color='k';
            app.NoLabelPrintLamp.Color='k';
            app.CapmissingLamp.Color='k';
            app.NormalLamp_2.Color='k';
        end

        % Button pushed function: LoadanImageButton
        function load_button(app, event)
            [FileName,PathName] = uigetfile('*.*','Select the MATLAB image file');
                im_original=imread(strcat(PathName,FileName));
                imshow(im_original,'Parent',app.UIAxes);
               set(app.UIAxes,'HandleVisibility','OFF');
            app.DeformedBottleLamp.Color='k';
            app.OverfilledLamp.Color='k';
            app.UnderfilledLamp.Color='k';
            app.LabelNotPresentLamp.Color='k';
            app.LabelNoStraightLamp.Color='k';
            app.NoLabelPrintLamp.Color='k';
            app.CapmissingLamp.Color='k';
            app.NormalLamp_2.Color='k';
            
            str_inst = strcat(PathName,FileName);
            img = imread(str_inst);
            img_grayscale = rgb2gray(img);
        
            img_grayscale_red = img(:,:,1);
            img_grayscale_red = imadjust(img_grayscale_red);
             img_grayscale_m = rgb2gray(img(:,:,:));
    img_grayscale_extracted_m = img_grayscale_m(5:65,120:240);
    img_thresh_m = ~(img_grayscale_extracted_m>180);
            %         figure;
            %         imshow(img_grayscale_m,[])
    se_m = strel ('rectangle' , [10,2]);
    img_thresh_m = imclose (img_thresh_m,se_m);
    [r , c] = find( img_thresh_m == 1);
        
    c_avg = 0;
    if ( size(c,1) > 0)
        c_avg = sum (c)/size(c,1); % use see
    end
    
    if (c_avg < 1)
        c_avg = 60;
    end
    c_avg = floor(c_avg);
    
    uf_x = 120+c_avg - 60;
            uf_y = 140;
            uf_l = 120;
            uf_w = 20;
            
            ov_x = 120+c_avg - 60;
            ov_y = 100;
            ov_l = 120;
            ov_w = 40;
    
            
            nl_x = 120+c_avg - 60;
            nl_y = 180;
            nl_l = 120;
            nl_w = 100;
            
            nlp_x = 120+c_avg - 60;
            nlp_y = 180;
            nlp_l = 120;
            nlp_w = 100;
            
            d1_x =120+c_avg +40 ;
            d1_y = 180;
            d1_l = 26;
            d1_w = 91;
            
            d2_x =120+c_avg - 60-5;
            d2_y = 180;
            d2_l = 26;
            d2_w =91 ;
            
            lns_x = 120+c_avg - 60;
            lns_y = 170;
            lns_l = 120;
            lns_w = 30;
            
            cm_x =120+c_avg -40 ;
            cm_y = 10;
            cm_l = 80;
            cm_w = 40;
            
            dm_x =120+c_avg -50;
            dm_y = 150;
            dm_l = 100;
            dm_w = 100;
            
    img_grayscale_extracted = img_grayscale(135:155,120:240);
    img_grayscale_extracted2 = img_grayscale(115:135,120:240);
    img_grayscale_extracted3 = img_grayscale(180:280,120:240);
    img_grayscale_extracted4 = img_grayscale(180:280,120:240);
    img_grayscale_extracted5 = img_grayscale(10:45,150:200);
    img_grayscale_extracted6 = img_grayscale(1:280,130:220);
    img_grayscale_extracted7 = img_grayscale(170:190,115:234);
    img_grayscale_extracted8 = (img_grayscale_red(180:270,((120+c_avg+60-20):(120+c_avg+60))));
    img_grayscale_extracted_b = (img_grayscale_red(180:270,((120+c_avg-60):(120+c_avg-60+20)))); 
    img_grayscale_extracted9 = img_grayscale_red(190:260,110:230);
    img_grayscale_extracted10 = (img_grayscale(80:170,135:230));
    img_grayscale_extracted11 = rgb2gray(img(80:170,(120+c_avg-40):(120+c_avg+40),:));
    img_grayscale_extracted12 = img_grayscale_red(180:270,260:340);
    img_grayscale_extracted13 = (img_grayscale_red(190:260,280:350));
    
    img_edge1 = edge (img_grayscale_extracted11,'canny');    
    se = strel('rectangle',[2 1]);
    img_edge1 = imopen(img_edge1,se);
            
        
    img_edge1 = bwareaopen(img_edge1,15);
    val_cmp = sum (img_edge1(:));
            
    img_thresh = ~(img_grayscale_extracted>150);
    img_thresh2 = ~(img_grayscale_extracted2>150);
    img_thresh3 = ~(img_grayscale_extracted3>70);
    img_thresh4 = ~(img_grayscale_extracted4>150);
    img_thresh5 = ~(img_grayscale_extracted5>150);
    img_thresh6 = ~(img_grayscale_extracted6>150 & img_grayscale_extracted6<230);
    img_thresh7 = ~(img_grayscale_extracted8>180);
    se = strel('rectangle',[50 3]);
    img_thres_b = ~(img_grayscale_extracted_b>180);
    img_thres_b = imopen(img_thres_b,se);
    img_thresh7 = imopen(img_thresh7,se);
    img_thresh8 = (img_grayscale_extracted9>100);
    img_thresh11 = ~(img_grayscale_extracted11 > 100);
    img_thresh12 = ~(img_grayscale_extracted12 > 100);
    img_thresh13 = ~(img_grayscale_extracted13>180);
            
      
            %     figure;
            %     imshow(img_grayscale_extracted13,[])
            %     figure;
            %     imshow(img_thresh13,[])
            %     img_thresh13 = imopen(img_thresh13,se);
            %   
            
    dimension = 20 * 120;
    dimension2 = 20 * 120;
    dimension3 = 100 * 120;
    dimension4 = 100 * 120;
    dimension5 = 45 * 50;
    dimension6 = 280 * 90;
    dimension7 = 71*26;
    dimension11 = 81 * 91;
    dimension12 = 81 * 91;
    dimension13 = 71*71;
            
    no_of_white_pixels = sum(img_thresh(:));
    no_of_white_pixels2 = sum(img_thresh2(:));
    no_of_white_pixels3 = sum(img_thresh3(:));
    no_of_white_pixels4 = sum(img_thresh4(:));
    no_of_white_pixels5 = sum(img_thresh5(:));
    no_of_white_pixels6 = sum(img_thresh6(:));
    no_of_white_pixels7 = sum(img_thresh7(:));
    no_of_white_pixels_b = sum(img_thres_b(:));
            
    percentage = no_of_white_pixels/dimension *100;
    percentage2 = no_of_white_pixels2/dimension2 *100;
    percentage3 = no_of_white_pixels3/dimension3 *100;
    percentage4 = no_of_white_pixels4/dimension4 *100;
    percentage5 = no_of_white_pixels5/dimension5 *100;
    percentage6 = no_of_white_pixels6/dimension6 *100;
    percentage7 = (no_of_white_pixels7/dimension7) * 100 ;
    percentage_b = (no_of_white_pixels_b/dimension7) * 100 ;
    percentage8 = (sum(img_thresh8(:))/(71*121))*100;
    percentage11 = (sum(img_thresh11(:))/dimension11)*100;
    percentage12 = (sum(img_thresh12(:))/dimension12)*100;
    percentage13 = (sum(img_thresh13(:))/dimension13)*100;
            
            
    img_edge = edge (img_grayscale_extracted7,'canny',0.5);
        se = strel ('rectangle',[1 1]);
        img_edge= imclose (img_edge,se);
        
        mirror_ref = double(img_edge(:,1:60));
        mirror1 = double(img_edge (:, 61:end));
        mirror1 = flip(mirror1,2);
        a = (conv2(mirror1,mirror_ref));
        ssimval = max(abs(a(:)));  
               
    [H,T,R] = hough(img_edge);
    P  = houghpeaks(H);
    lines = houghlines(img_edge,T,R,P);
    max_len = 0;
    for k = 1:length(lines)
        xy = [lines(k).point1; lines(k).point2];
        len = norm(lines(k).point1 - lines(k).point2);
        if ( len > max_len)
            max_len = len;
            xy_long = xy;
        end
    end
             
            
            %     disp(i)  
    flag = 0;
    flag_under =0;


    if ( percentage6 < 5)
        flag = 0;        
        
    else


         if ( percentage < 20)
             app.UnderfilledLamp.Color='red';
             rectangle(app.UIAxes,'Position', [uf_x,uf_y,uf_l,uf_w],'EdgeColor','b')
             flag_under = 1;
             flag =1;
         end
            
         if ( percentage2 > 20)
             rectangle(app.UIAxes,'Position', [ov_x,ov_y,ov_l,ov_w],'EdgeColor','b') 
             app.OverfilledLamp.Color='red';
             flag =1;
         end
        
        if ( percentage5 < 30)
            rectangle(app.UIAxes,'Position', [cm_x,cm_y,cm_l,cm_w],'EdgeColor','b')    
            app.CapmissingLamp.Color ='red';
            flag =1;
        end
    
        
        if( percentage3 > 50)
            app.LabelNotPresentLamp.Color ='red';
            rectangle(app.UIAxes,'Position', [nl_x,nl_y,nl_l,nl_w],'EdgeColor','b')
            flag =1;


        else
            if ( percentage4 < 30)
            app.NoLabelPrintLamp.Color ='red';
            rectangle(app.UIAxes,'Position', [nlp_x,nlp_y,nlp_l,nlp_w],'EdgeColor','b') 
            flag =1;


            elseif ((max_len < 47 || percentage8 < 86) && ssimval < 27 )
                rectangle(app.UIAxes,'Position', [lns_x,lns_y,lns_l,lns_w],'EdgeColor','b') 
                app.LabelNoStraightLamp.Color ='red';
                flag =1;
            elseif ((ssimval < 12 && max_len <60)||(ssimval < 30 && max_len >80) )
                rectangle(app.UIAxes,'Position', [lns_x,lns_y,lns_l,lns_w],'EdgeColor','b')
                app.LabelNoStraightLamp.Color ='red';
                flag =1;


            elseif (((percentage7 >31 | percentage_b >35))& flag_under ~= 1)
                app.DeformedBottleLamp.Color='red';
                flag =1;
                if (percentage7 >31)
                    rectangle(app.UIAxes,'Position', [d1_x,d1_y,d1_l,d1_w],'EdgeColor','b')
                end
                if (percentage_b>35)             
                   rectangle(app.UIAxes,'Position', [d2_x,d2_y,d2_l,d2_w],'EdgeColor','b')         
                end
         
            elseif (val_cmp > 350 & flag_under ~= 1 & percentage11<80 & percentage7<5 & percentage_b < 5)
                app.DeformedBottleLamp.Color='red';
                rectangle(app.UIAxes,'Position', [dm_x,dm_y,dm_l,dm_w],'EdgeColor','b')
                flag = 1;
            end
        end
            

    end
        
    if (flag == 0)
            app.NormalLamp_2.Color ='red';
    end


        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.DeformedBottleLampLabel = uilabel(app.UIFigure);
            app.DeformedBottleLampLabel.Text = 'Deformed Bottle';
            app.DeformedBottleLamp = uilamp(app.UIFigure);
            app.OverfilledLampLabel = uilabel(app.UIFigure);
            app.OverfilledLampLabel.Text = 'Over-filled';
            app.OverfilledLamp = uilamp(app.UIFigure);
            app.UnderfilledLampLabel = uilabel(app.UIFigure);
            app.UnderfilledLampLabel.Text = 'Under-filled';
            app.UnderfilledLamp = uilamp(app.UIFigure);
            app.LabelNotPresentLampLabel = uilabel(app.UIFigure);
            app.LabelNotPresentLampLabel.Text = 'Label Not Present';
            app.LabelNotPresentLamp = uilamp(app.UIFigure);
            app.LabelNoStraightLampLabel = uilabel(app.UIFigure);
            app.LabelNoStraightLampLabel.Text = 'Label No Straight';
            app.LabelNoStraightLamp = uilamp(app.UIFigure);
            app.NoLabelPrintLampLabel = uilabel(app.UIFigure);
            app.NoLabelPrintLampLabel.Text = 'No Label Print';
            app.NoLabelPrintLamp = uilamp(app.UIFigure);
            app.CapmissingLampLabel = uilabel(app.UIFigure);
            app.CapmissingLampLabel.Text = 'Capmissing';
            app.CapmissingLamp = uilamp(app.UIFigure);
            app.LoadanImageButton = uibutton(app.UIFigure, 'push');
            app.LoadanImageButton.ButtonPushedFcn = createCallbackFcn(app, @load_button, true);
            app.LoadanImageButton.Text = 'Load an Image';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Image')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.NormalLamp_2Label = uilabel(app.UIFigure);
            app.NormalLamp_2Label.Text = 'Normal';
            app.NormalLamp_2 = uilamp(app.UIFigure);
            app.CreatedByLabel = uilabel(app.UIFigure);
            app.CreatedByLabel.Text = 'Created By:   ';
            app.AbdullahThabitLabel = uilabel(app.UIFigure);
            app.AbdullahThabitLabel.Text = 'Abdullah Thabit';
            app.ZohaibSalahuddinLabel = uilabel(app.UIFigure);
            app.ZohaibSalahuddinLabel.Text = 'Zohaib Salahuddin';
            app.TewodrosAregaLabel = uilabel(app.UIFigure);
            app.TewodrosAregaLabel.Text = 'Tewodros Arega';
            app.ImageProcessingProjectLabel = uilabel(app.UIFigure);
            app.ImageProcessingProjectLabel.Text = 'Image Processing Project';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app2

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end